classdef finalProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        InputParametersTab            matlab.ui.container.Tab
        LaminateMaterialPropertiesPanel  matlab.ui.container.Panel
        E1EditFieldLabel              matlab.ui.control.Label
        E1EditField                   matlab.ui.control.NumericEditField
        E2EditFieldLabel              matlab.ui.control.Label
        E2EditField                   matlab.ui.control.NumericEditField
        G12EditFieldLabel             matlab.ui.control.Label
        G12EditField                  matlab.ui.control.NumericEditField
        v12EditFieldLabel             matlab.ui.control.Label
        v12EditField                  matlab.ui.control.NumericEditField
        alpha1EditFieldLabel          matlab.ui.control.Label
        alpha1EditField               matlab.ui.control.NumericEditField
        alpha2EditFieldLabel          matlab.ui.control.Label
        alpha2EditField               matlab.ui.control.NumericEditField
        DropDownE1                    matlab.ui.control.DropDown
        DropDownE2                    matlab.ui.control.DropDown
        DropDownG12                   matlab.ui.control.DropDown
        DropDownv12                   matlab.ui.control.DropDown
        DropDownalpha1                matlab.ui.control.DropDown
        DropDownalpha2                matlab.ui.control.DropDown
        LaminateStrengthPropertiesPanel  matlab.ui.container.Panel
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        XEditField_2Label             matlab.ui.control.Label
        XprimeEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        YEditField_2Label             matlab.ui.control.Label
        YprimeEditField               matlab.ui.control.NumericEditField
        SEditFieldLabel               matlab.ui.control.Label
        SEditField                    matlab.ui.control.NumericEditField
        StrengthStressLabel           matlab.ui.control.Label
        StrengthStrainLabel           matlab.ui.control.Label
        XeEditFieldLabel              matlab.ui.control.Label
        XeEditField                   matlab.ui.control.NumericEditField
        DropDownX                     matlab.ui.control.DropDown
        XeEditField_2Label            matlab.ui.control.Label
        XePrimeEditField              matlab.ui.control.NumericEditField
        YeEditFieldLabel              matlab.ui.control.Label
        YeEditField                   matlab.ui.control.NumericEditField
        YeEditField_2Label            matlab.ui.control.Label
        YePrimeEditField              matlab.ui.control.NumericEditField
        SeEditFieldLabel              matlab.ui.control.Label
        SeEditField                   matlab.ui.control.NumericEditField
        DropDownXprime                matlab.ui.control.DropDown
        DropDownY                     matlab.ui.control.DropDown
        DropDownYprime                matlab.ui.control.DropDown
        DropDownS                     matlab.ui.control.DropDown
        CompositeMechanicalLoadingConditionsPanel  matlab.ui.container.Panel
        NxEditFieldLabel              matlab.ui.control.Label
        NxEditField                   matlab.ui.control.NumericEditField
        NyEditFieldLabel              matlab.ui.control.Label
        NyEditField                   matlab.ui.control.NumericEditField
        NxyEditFieldLabel             matlab.ui.control.Label
        NxyEditField                  matlab.ui.control.NumericEditField
        MxEditFieldLabel              matlab.ui.control.Label
        MxEditField                   matlab.ui.control.NumericEditField
        MyEditFieldLabel              matlab.ui.control.Label
        MyEditField                   matlab.ui.control.NumericEditField
        MxyEditFieldLabel             matlab.ui.control.Label
        MxyEditField                  matlab.ui.control.NumericEditField
        DropDown_4                    matlab.ui.control.DropDown
        DropDown_5                    matlab.ui.control.DropDown
        DropDown_15                   matlab.ui.control.DropDown
        DropDown_16                   matlab.ui.control.DropDown
        DropDown_17                   matlab.ui.control.DropDown
        DropDown_18                   matlab.ui.control.DropDown
        CompositeAnalysisInputParametersLabel  matlab.ui.control.Label
        CompositeHygrothermalLoadingConditionsPanel  matlab.ui.container.Panel
        T0EditFieldLabel              matlab.ui.control.Label
        T0EditField                   matlab.ui.control.NumericEditField
        TfEditFieldLabel              matlab.ui.control.Label
        TfEditField                   matlab.ui.control.NumericEditField
        DropDownT0                    matlab.ui.control.DropDown
        DropDownTf                    matlab.ui.control.DropDown
        ExcelDataOptionalPanel        matlab.ui.container.Panel
        IfSelectingFileOtherThanAE535InputDataxlsmLabel  matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        IffileAE535InputDataxlsmisinCurrentWorkingDirectoryLabel  matlab.ui.control.Label
        LoadDataButton                matlab.ui.control.Button
        DirecotryTextAreaLabel        matlab.ui.control.Label
        DirecotryTextArea             matlab.ui.control.TextArea
        Lamp                          matlab.ui.control.Lamp
        LaminatePropertiesPanel       matlab.ui.container.Panel
        LayupDelimitedEditFieldLabel  matlab.ui.control.Label
        LayupEditField                matlab.ui.control.EditField
        SymmetricCheckBox             matlab.ui.control.CheckBox
        PlyThicknessEditFieldLabel    matlab.ui.control.Label
        PlyThicknessEditField         matlab.ui.control.NumericEditField
        DropDown_20                   matlab.ui.control.DropDown
        RunProgramPanel               matlab.ui.container.Panel
        RunButton                     matlab.ui.control.Button
        LoadingTab                    matlab.ui.container.Tab
    end

    
    properties (Access = private)
        % Defaul excel file name
        defaultFileName = 'AE535 - InputData.xlsm';  
        % Default file directory
        % Since file is in working directory, only file name is needed
        fileDirectory = 'AE535 - InputData.xlsm';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % Pulling file name
            fileName = app.defaultFileName;
            
            % If proper .xlsm file is selcted for loading data
            if exist(fileName, "file") || ...
                    (~strcmp(app.DirecotryTextArea.Value, ...
                    'No File Selected'))
                % Turn lamp green
                app.Lamp.Color = [0 1 0];
            else
                % Turn lamp red
                app.Lamp.Color = [1 0 0];
            end
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % Getting file name and directory for selected .xlsm file
            [file, path] = uigetfile('*xlsm');
            
            % If no file is selected
            if isequal(file, 0)
                
                % Display text in text are
                app.DirecotryTextArea.Value = 'No File Selected';
                
            else
                
                % Display full directory in text area
                app.DirecotryTextArea.Value = strcat(path, file);
                
                % Storing file directory
                app.fileDirectory = strcat(path, file);
                
            end
            
            % Make text area and lable visible
            app.DirecotryTextArea.Visible = 'on';
            app.DirecotryTextAreaLabel.Visible = 'on';
             
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            % Material properties names
            editFieldNames.valueNames = {'E1', 'E2', 'G12', 'v12', ...
                'alpha1', 'alpha2', 'X', 'Xprime', 'Y', 'Yprime', 'S', ...
                'Xe', 'XePrime', 'Ye', 'YePrime', 'Se', 'Nx', 'Ny', ...
                'Nxy', 'Mx', 'My', 'Mxy', 'PlyThickness', 'T0', 'Tf'};
            
            % Creating edit field names for material properties
            for ii = 1:length(editFieldNames.valueNames)
                editFieldNames.completeNames{ii} = strcat(...
                    editFieldNames.valueNames{ii}, 'EditField');
                
            end
            
            % Getting values from material property edit fields and adding
            % to inputs structure
            for ii = 1:length(editFieldNames.completeNames)
                
                inputs.(editFieldNames.valueNames{ii}) = ...
                    app.(editFieldNames.completeNames{ii}).Value;
            end
            
            % Obtaining stacking sequence based on edit field
            if app.SymmetricCheckBox.Value == 1
                inputs.ss = ...
                [str2double(strsplit(app.LayupEditField.Value, '/')), ...
                flip(str2double(strsplit(app.LayupEditField.Value, '/')))];
                
            else
                
                inputs.ss = ...
                    str2double(strsplit(app.LayupEditField.Value, '/'));
            end
            
            % Material property unit conversions
            for ii = 1:6
                % If the selected unit is Msi
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, 'Msi') == 1
                    continue
                else
                    % Converting GPa to Msi
                    inputs.(editFieldNames.valueNames{ii}) = ...
                        (editFieldNames.valueNames{ii}) * ...
                        (145038 * 10 ^ -6);
                end
            end
            
            % Strength unit conversion
            for ii = 7:11
                % If the selected unit is ksi
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, 'ksi') == 1
                    continue
                else
                    % Converting kPa to Msi
                    inputs.(editFieldNames.valueNames{ii}) = ...
                        (editFieldNames.valueNames{ii}) * ...
                        (145038 * 10 ^ -9);
                end
            end
            
            % Force unit conversion
            for ii = 17:19
               % If the selected unit is lb
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, 'lb') == 1
                    continue
                else
                    % Converting N to lbf
                    inputs.(editFieldNames.valueNames{ii}) = ...
                        (editFieldNames.valueNames{ii}) * ...
                        (.224809);
                end
            end
            
            % Moment unit conversion
            for ii = 20:22
                % If the selected unit is lbin
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, 'lbin') == 1
                    continue
                else
                    % Converting Nm to lbin
                    inputs.(editFieldNames.valueNames{ii}) = ...
                        (editFieldNames.valueNames{ii}) * ...
                        (8.85075);
                end   
            end
            
            % Thickness unit conversion
            if strcmp(app.(strcat('DropDown', ...
                    editFieldNames.valueNames{23})).Value, 'lbin') == 0
           
                % Converting mm to in
                inputs.(editFieldNames.valueNames{23}) = ...
                    (editFieldNames.valueNames{23}) * ...
                    (.0393701);
            end
            
            % Alpha unit conversions
            for ii = 24:25
                 % If the selected unit is 1/F
                if strcmp(app.(strcat('DropDown', ...
                        editFieldNames.valueNames{ii})).Value, '1/F') == 1
                    continue
                else
                    % Converting 1/F to 1/C
                    inputs.(editFieldNames.valueNames{ii}) =( ...
                        ((1/(editFieldNames.valueNames{ii})) * ...
                        (9/5)) + 32) ^ -1;
                end    
            end
            
            % Run complete composite analysis code
            [outputs] = main()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputParametersTab = uitab(app.TabGroup);
            app.InputParametersTab.Title = 'Input Parameters';
            app.LaminateMaterialPropertiesPanel = uipanel(app.InputParametersTab);
            app.LaminateMaterialPropertiesPanel.Title = 'Laminate Material Properties';
            app.E1EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.E1EditFieldLabel.Text = 'E1 ';
            app.E1EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.E2EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.E2EditFieldLabel.Text = 'E2';
            app.E2EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.G12EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.G12EditFieldLabel.Text = 'G12';
            app.G12EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.v12EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.v12EditFieldLabel.Text = 'v12';
            app.v12EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.alpha1EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.alpha2EditFieldLabel = uilabel(app.LaminateMaterialPropertiesPanel);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.LaminateMaterialPropertiesPanel, 'numeric');
            app.DropDownE1 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownE1.Items = {'Msi', 'GPa'};
            app.DropDownE1.Value = 'Msi';
            app.DropDownE2 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownE2.Items = {'Msi', 'GPa'};
            app.DropDownE2.Value = 'Msi';
            app.DropDownG12 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownG12.Items = {'Msi', 'GPa'};
            app.DropDownG12.Value = 'Msi';
            app.DropDownv12 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownv12.Items = {'Msi', 'GPa'};
            app.DropDownv12.Value = 'Msi';
            app.DropDownalpha1 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownalpha1.Items = {'Msi', 'GPa'};
            app.DropDownalpha1.Value = 'Msi';
            app.DropDownalpha2 = uidropdown(app.LaminateMaterialPropertiesPanel);
            app.DropDownalpha2.Items = {'Msi', 'GPa'};
            app.DropDownalpha2.Value = 'Msi';
            app.LaminateStrengthPropertiesPanel = uipanel(app.InputParametersTab);
            app.LaminateStrengthPropertiesPanel.Title = 'Laminate Strength Properties';
            app.XEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.XEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XEditField_2Label.Text = 'X''';
            app.XprimeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YEditField_2Label.Text = 'Y''';
            app.YprimeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.SEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.SEditFieldLabel.Text = 'S';
            app.SEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.StrengthStressLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.StrengthStressLabel.Text = 'Strength (Stress)';
            app.StrengthStrainLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.StrengthStrainLabel.Text = 'Strength (Strain)';
            app.XeEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XeEditFieldLabel.Text = 'Xe';
            app.XeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.DropDownX = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownX.Items = {'ksi', 'kPa'};
            app.DropDownX.Value = 'ksi';
            app.XeEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.XeEditField_2Label.Text = 'X''e';
            app.XePrimeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YeEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YeEditFieldLabel.Text = 'Ye';
            app.YeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.YeEditField_2Label = uilabel(app.LaminateStrengthPropertiesPanel);
            app.YeEditField_2Label.Text = 'Y''e';
            app.YePrimeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.SeEditFieldLabel = uilabel(app.LaminateStrengthPropertiesPanel);
            app.SeEditFieldLabel.Text = 'Se';
            app.SeEditField = uieditfield(app.LaminateStrengthPropertiesPanel, 'numeric');
            app.DropDownXprime = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownXprime.Items = {'ksi', 'kPa'};
            app.DropDownXprime.Value = 'ksi';
            app.DropDownY = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownY.Items = {'ksi', 'kPa'};
            app.DropDownY.Value = 'ksi';
            app.DropDownYprime = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownYprime.Items = {'ksi', 'kPa'};
            app.DropDownYprime.Value = 'ksi';
            app.DropDownS = uidropdown(app.LaminateStrengthPropertiesPanel);
            app.DropDownS.Items = {'ksi', 'kPa'};
            app.DropDownS.Value = 'ksi';
            app.CompositeMechanicalLoadingConditionsPanel = uipanel(app.InputParametersTab);
            app.CompositeMechanicalLoadingConditionsPanel.Title = 'Composite Mechanical Loading Conditions';
            app.NxEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.NyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.NxyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.NxyEditFieldLabel.Text = 'Nxy';
            app.NxyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.MxEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.MxEditFieldLabel.Text = 'Mx';
            app.MxEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.MyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.MyEditFieldLabel.Text = 'My';
            app.MyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.MxyEditFieldLabel = uilabel(app.CompositeMechanicalLoadingConditionsPanel);
            app.MxyEditFieldLabel.Text = 'Mxy';
            app.MxyEditField = uieditfield(app.CompositeMechanicalLoadingConditionsPanel, 'numeric');
            app.DropDown_4 = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDown_4.Items = {'lb', 'N'};
            app.DropDown_4.Value = 'lb';
            app.DropDown_5 = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDown_5.Items = {'lbin', 'Nm'};
            app.DropDown_5.Value = 'lbin';
            app.DropDown_15 = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDown_15.Items = {'lb', 'N'};
            app.DropDown_15.Value = 'lb';
            app.DropDown_16 = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDown_16.Items = {'lb', 'N'};
            app.DropDown_16.Value = 'lb';
            app.DropDown_17 = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDown_17.Items = {'lbin', 'Nm'};
            app.DropDown_17.Value = 'lbin';
            app.DropDown_18 = uidropdown(app.CompositeMechanicalLoadingConditionsPanel);
            app.DropDown_18.Items = {'lbin', 'Nm'};
            app.DropDown_18.Value = 'lbin';
            app.CompositeAnalysisInputParametersLabel = uilabel(app.InputParametersTab);
            app.CompositeAnalysisInputParametersLabel.Text = 'Composite Analysis Input Parameters';
            app.CompositeHygrothermalLoadingConditionsPanel = uipanel(app.InputParametersTab);
            app.CompositeHygrothermalLoadingConditionsPanel.Title = 'Composite Hygrothermal Loading Conditions';
            app.T0EditFieldLabel = uilabel(app.CompositeHygrothermalLoadingConditionsPanel);
            app.T0EditFieldLabel.Text = 'T0';
            app.T0EditField = uieditfield(app.CompositeHygrothermalLoadingConditionsPanel, 'numeric');
            app.TfEditFieldLabel = uilabel(app.CompositeHygrothermalLoadingConditionsPanel);
            app.TfEditFieldLabel.Text = 'Tf';
            app.TfEditField = uieditfield(app.CompositeHygrothermalLoadingConditionsPanel, 'numeric');
            app.DropDownT0 = uidropdown(app.CompositeHygrothermalLoadingConditionsPanel);
            app.DropDownT0.Items = {'1/F', '1/C'};
            app.DropDownT0.Value = '1/F';
            app.DropDownTf = uidropdown(app.CompositeHygrothermalLoadingConditionsPanel);
            app.DropDownTf.Items = {'1/F', '1/C'};
            app.DropDownTf.Value = '1/F';
            app.ExcelDataOptionalPanel = uipanel(app.InputParametersTab);
            app.ExcelDataOptionalPanel.Title = 'Excel Data (Optional)';
            app.IfSelectingFileOtherThanAE535InputDataxlsmLabel = uilabel(app.ExcelDataOptionalPanel);
            app.IfSelectingFileOtherThanAE535InputDataxlsmLabel.Text = 'If Selecting File Other Than "AE535 - Input Data.xlsm" : ';
            app.BrowseButton = uibutton(app.ExcelDataOptionalPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.IffileAE535InputDataxlsmisinCurrentWorkingDirectoryLabel = uilabel(app.ExcelDataOptionalPanel);
            app.IffileAE535InputDataxlsmisinCurrentWorkingDirectoryLabel.Text = 'If file "AE 535 - Input Data.xlsm" is in Current Working Directory: ';
            app.LoadDataButton = uibutton(app.ExcelDataOptionalPanel, 'push');
            app.LoadDataButton.Text = 'Load Data';
            app.DirecotryTextAreaLabel = uilabel(app.ExcelDataOptionalPanel);
            app.DirecotryTextAreaLabel.Visible = 'off';
            app.DirecotryTextAreaLabel.Text = 'Direcotry:';
            app.DirecotryTextArea = uitextarea(app.ExcelDataOptionalPanel);
            app.DirecotryTextArea.Visible = 'off';
            app.Lamp = uilamp(app.ExcelDataOptionalPanel);
            app.LaminatePropertiesPanel = uipanel(app.InputParametersTab);
            app.LaminatePropertiesPanel.Title = 'Laminate Properties';
            app.LayupDelimitedEditFieldLabel = uilabel(app.LaminatePropertiesPanel);
            app.LayupDelimitedEditFieldLabel.Text = 'Layup (''/'' Delimited)';
            app.LayupEditField = uieditfield(app.LaminatePropertiesPanel, 'text');
            app.SymmetricCheckBox = uicheckbox(app.LaminatePropertiesPanel);
            app.SymmetricCheckBox.Text = 'Symmetric';
            app.PlyThicknessEditFieldLabel = uilabel(app.LaminatePropertiesPanel);
            app.PlyThicknessEditFieldLabel.Text = 'PlyThickness';
            app.PlyThicknessEditField = uieditfield(app.LaminatePropertiesPanel, 'numeric');
            app.DropDown_20 = uidropdown(app.LaminatePropertiesPanel);
            app.DropDown_20.Items = {'in', 'mm'};
            app.DropDown_20.Value = 'in';
            app.RunProgramPanel = uipanel(app.InputParametersTab);
            app.RunProgramPanel.Title = 'Run Program';
            app.RunButton = uibutton(app.RunProgramPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.LoadingTab = uitab(app.TabGroup);
            app.LoadingTab.Title = 'Loading';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end